classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayoutEdit              matlab.ui.container.GridLayout
        RedoButton                  matlab.ui.control.Button
        UndoButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        PreviewButton               matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        LowPassFilterTab            matlab.ui.container.Tab
        GridLayoutLowPass           matlab.ui.container.GridLayout
        LowPassEquationLabel        matlab.ui.control.Label
        LowPassFilterOrderSpinner   matlab.ui.control.Spinner
        FilterOrderSpinnerLabel     matlab.ui.control.Label
        LowPassRadiusSlider         matlab.ui.control.Slider
        RadiusSliderLabel           matlab.ui.control.Label
        LowPassModeDropDown         matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        HighPassFilterTab           matlab.ui.container.Tab
        GridLayoutHighPass          matlab.ui.container.GridLayout
        HighPassEquationLabel       matlab.ui.control.Label
        HighPassFilterOrderSpinner  matlab.ui.control.Spinner
        FilterOrderSpinnerLabel_2   matlab.ui.control.Label
        HighPassRadiusSlider        matlab.ui.control.Slider
        RadiusSliderLabel_2         matlab.ui.control.Label
        HighPassModeDropDown        matlab.ui.control.DropDown
        ModeDropDownLabel_2         matlab.ui.control.Label
        BandRejectTab               matlab.ui.container.Tab
        GridLayoutBand              matlab.ui.container.GridLayout
        BandFilterOrderSpinner      matlab.ui.control.Spinner
        FilterOrderSpinnerLabel_3   matlab.ui.control.Label
        BandBandwidthSlider         matlab.ui.control.Slider
        CenterFrequencyD_0Label_2   matlab.ui.control.Label
        BandEquationLabel           matlab.ui.control.Label
        BandCenterFreqSlider        matlab.ui.control.Slider
        CenterFrequencyD_0Label     matlab.ui.control.Label
        BandModeDropDown            matlab.ui.control.DropDown
        ModeDropDownLabel_3         matlab.ui.control.Label
        NotchRejectTab              matlab.ui.container.Tab
        GridLayoutNotch             matlab.ui.container.GridLayout
        NotchEquationLabel          matlab.ui.control.Label
        NotchRadiusSlider           matlab.ui.control.Slider
        CenterFrequencyD_0Label_5   matlab.ui.control.Label
        NotchFilterOrderSpinner     matlab.ui.control.Spinner
        FilterOrderSpinnerLabel_4   matlab.ui.control.Label
        NotchVertLocSlider          matlab.ui.control.Slider
        CenterFrequencyD_0Label_4   matlab.ui.control.Label
        NotchHorizLocSlider         matlab.ui.control.Slider
        CenterFrequencyD_0Label_3   matlab.ui.control.Label
        NotchModeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel_4         matlab.ui.control.Label
        BrightnessSlider            matlab.ui.control.Slider
        BrightnessLabel             matlab.ui.control.Label
        FiltersLabel                matlab.ui.control.Label
        SaveImageButton             matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        FrequencyAxes               matlab.ui.control.UIAxes
        MainAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ImageEditorState
        
        PreviewImageListener
        PreviewFreqListener
        HasPreviewListener
        UndoListener
        RedoListener

        FilterPreviewActive
    end
    
    methods (Access = private)
        
        function updatePreviewImage(app)
            img = app.ImageEditorState.PreviewImage;
            if isempty(img)
                return;
            end

            cla(app.MainAxes);

            imshow(img, [0 1], 'Parent', app.MainAxes);
            axis(app.MainAxes, 'on');
            axis(app.MainAxes, 'image');
        end

        function updatePreviewFrequency(app)
            F = app.ImageEditorState.PreviewImageFreq;
            if isempty(F)
                return;
            end

            F = fftshift(F, 1);
            F = fftshift(F, 2);

            magnitude = abs(F);

            % Combine channels only for frequency visualization
            if size(magnitude, 3) > 1
                magnitude = mean(magnitude, 3);
            end
        
            spectrum = log(1 + magnitude);
        
            height = size(spectrum, 1);
            width  = size(spectrum, 2);
        
            fx = (-floor(width/2)  : ceil(width/2)-1)  / width;
            fy = (-floor(height/2) : ceil(height/2)-1) / height;
        
            imagesc(app.FrequencyAxes, fx, fy, spectrum);
        
            colormap(app.FrequencyAxes, gray);
        
            axis(app.FrequencyAxes, 'on');
            axis(app.FrequencyAxes, 'image');
        end
        
        function setFilterMode(app)
            tabName = app.TabGroup.SelectedTab.Title;
            switch tabName
                case "Low-Pass Filter"
                    orderSpinner = app.LowPassFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel;
                    modeDropdown = app.LowPassModeDropDown;
                    
                    equation = app.LowPassEquationLabel;
                    equationRowIdx = 1;
                case "High-Pass Filter"
                    orderSpinner = app.HighPassFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel_2;
                    modeDropdown = app.HighPassModeDropDown;
                    
                    equation = app.HighPassEquationLabel;
                    equationRowIdx = 2;
                case "Band Reject"
                    orderSpinner = app.BandFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel_3;
                    modeDropdown = app.BandModeDropDown;

                    equation = app.BandEquationLabel;
                    equationRowIdx = 3;
                case "Notch Reject"
                    orderSpinner = app.NotchFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel_4;
                    modeDropdown = app.NotchModeDropDown;

                    equation = app.NotchEquationLabel;
                    equationRowIdx = 4;
            end

            mode = modeDropdown.Value;
            switch mode
                case "Ideal"
                    equationColIdx = 1;
                case "Gaussian"
                    equationColIdx = 2;
                case "Butterworth"
                    equationColIdx = 3;
            end

            equation.Text = Equations.FilterEquations(equationRowIdx, equationColIdx);

            orderSpinner.Visible = "off";
            orderLabel.Visible = "off";
            if mode == "Butterworth"
                orderSpinner.Visible = "on";
                orderLabel.Visible = "on";
            end
        end
        
        function updatePreview(app, brightness)
            if nargin < 2
                brightness = app.BrightnessSlider.Value;
            end

            if isempty(app.ImageEditorState.PreviewImageFreq)
                return;
            end

            F = app.ImageEditorState.getCurrentImageFreq();

            if app.FilterPreviewActive
                switch app.TabGroup.SelectedTab.Title
                    case "Low-Pass Filter"
                        cutoff = app.LowPassRadiusSlider.Value;
                        switch app.LowPassModeDropDown.Value
                            case "Ideal"
                                F = LowPassFilter.ideal(F, cutoff);
                            case "Gaussian"
                                F = LowPassFilter.gaussian(F, cutoff);
                            case "Butterworth"
                                butterworthOrder = app.LowPassFilterOrderSpinner.Value;
                                F = LowPassFilter.butterworth(F, cutoff, butterworthOrder);
                        end
                    case "High-Pass Filter"
                        cutoff = app.HighPassRadiusSlider.Value;
                        switch app.LowPassModeDropDown.Value
                            case "Ideal"
                                F = HighPassFilter.ideal(F, cutoff);
                            case "Gaussian"
                                F = HighPassFilter.gaussian(F, cutoff);
                            case "Butterworth"
                                butterworthOrder = app.HighPassFilterOrderSpinner.Value;
                                F = HighPassFilter.butterworth(F, cutoff, butterworthOrder);
                        end
                    case "Band Reject"
                        centralFreq = app.BandCenterFreqSlider.Value;
                        bandwidth = app.BandBandwidthSlider.Value;
                        switch app.LowPassModeDropDown.Value
                            case "Ideal"
                                F = BandRejectFilter.ideal(F, centralFreq, bandwidth);
                            case "Gaussian"
                                F = BandRejectFilter.gaussian(F, centralFreq, bandwidth);
                            case "Butterworth"
                                butterworthOrder = app.BandFilterOrderSpinner.Value;
                                F = BandRejectFilter.butterworth(F, centralFreq, bandwidth, butterworthOrder);
                        end
                    case "Notch Reject"
                        horizontalLocation = app.NotchHorizLocSlider.Value;
                        verticalLocation = app.NotchVertLocSlider.Value;
                        radius = app.NotchRadiusSlider.Value;
                        switch app.NotchModeDropDown.Value
                            case "Ideal"
                                F = NotchRejectFilter.ideal(F, horizontalLocation, verticalLocation, radius);
                            case "Gaussian"
                                F = NotchRejectFilter.gaussian(F, horizontalLocation, verticalLocation, radius);
                            case "Butterworth"
                                butterworthOrder = app.NotchFilterOrderSpinner.Value;
                                F = NotchRejectFilter.butterworth(F, horizontalLocation, verticalLocation, butterworthOrder);
                        end
                end
            end

            % Scale DC for brightness
            F(1,1,:) = F(1,1,:) .* brightness;

            app.ImageEditorState.preview(F);
        end
        
        function updateButtonStates(app)
            if app.ImageEditorState.HasPreview
                app.ApplyButton.Enable = 'on';
                app.CancelButton.Enable = 'on';
            else
                app.ApplyButton.Enable = 'off';
                app.CancelButton.Enable = 'off';
            end

            if app.ImageEditorState.CanUndo
                app.UndoButton.Enable = "on";
            else
                app.UndoButton.Enable = "off";
            end
            if app.ImageEditorState.CanRedo
                app.RedoButton.Enable = "on";
            else
                app.RedoButton.Enable = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageEditorState = ImageEditorState();

            app.PreviewImageListener = addlistener( ...
                app.ImageEditorState, ...
                'PreviewImage', ...
                'PostSet', ...
                @(src, event) app.updatePreviewImage());

            app.PreviewFreqListener = addlistener( ...
                app.ImageEditorState, ...
                'PreviewImageFreq', ...
                'PostSet', ...
                @(src, event) app.updatePreviewFrequency);

            app.HasPreviewListener = addlistener(...
                app.ImageEditorState, ...
                'HasPreview', ...
                'PostSet', ...
                @(src, event) app.updateButtonStates());

            app.UndoListener = addlistener(...
                app.ImageEditorState, ...
                'CanUndo', ...
                'PostSet', ...
                @(src, event) app.updateButtonStates());

            app.RedoListener = addlistener(...
                app.ImageEditorState, ...
                'CanRedo', ...
                'PostSet', ...
                @(src, event) app.updateButtonStates());
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile( ...
                {'*.png;*.jpg;*.jpeg;*.bmp;*.tif;*.tiff', 'Image Files'});

            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            app.ImageEditorState.loadImage(img);

            app.BrightnessSlider.Enable = "on";
            app.SaveImageButton.Enable = "on";
            app.PreviewButton.Enable = "on";
            app.updateButtonStates();
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            app.updatePreview(event.Value);
        end

        % Value changed function: LowPassModeDropDown
        function LowPassModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Value changed function: HighPassModeDropDown
        function HighPassModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Value changed function: BandModeDropDown
        function BandModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Value changed function: NotchModeDropDown
        function NotchModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ImageEditorState.apply();
            app.BrightnessSlider.Value = 1;
            app.FilterPreviewActive = false;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.ImageEditorState.cancel();
            app.FilterPreviewActive = false;
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.FilterPreviewActive = true;
            app.updatePreview();
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.ImageEditorState.undo();

            app.FilterPreviewActive = false;
            app.BrightnessSlider.Value = 1;
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            app.ImageEditorState.redo();

            app.FilterPreviewActive = false;
            app.BrightnessSlider.Value = 1;
        end

        % Value changed function: LowPassRadiusSlider
        function LowPassRadiusSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: LowPassFilterOrderSpinner
        function LowPassFilterOrderSpinnerValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: HighPassRadiusSlider
        function HighPassRadiusSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: HighPassFilterOrderSpinner
        function HighPassFilterOrderSpinnerValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: BandCenterFreqSlider
        function BandCenterFreqSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: BandBandwidthSlider
        function BandBandwidthSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: BandFilterOrderSpinner
        function BandFilterOrderSpinnerValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: NotchHorizLocSlider
        function NotchHorizLocSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: NotchVertLocSlider
        function NotchVertLocSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: NotchRadiusSlider
        function NotchRadiusSliderValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: NotchFilterOrderSpinner
        function NotchFilterOrderSpinnerValueChanged(app, event)
            app.updatePreview();
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            img = app.ImageEditorState.getCurrentImage();
            if isempty(img)
                uialert(app.UIFigure, 'No image has been loaded.', 'Save Image');
                return;
            end

            [file, path] = uiputfile( ...
                { ...
                '*.png',  'PNG Image (*.png)'; ...
                '*.jpg',  'JPEG Image (*.jpg)'; ...
                '*.tif',  'TIFF Image (*.tif)'; ...
                '*.bmp',  'Bitmap Image (*.bmp)' ...
                }, ...
                'Save Image');

            if isequal(file, 0)
                return;
            end

            img = min(max(img, 0), 1);

            imwrite(img, fullfile(path, file));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {964, 964};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {610, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutEdit = uigridlayout(app.LeftPanel);
            app.LoadImageButton = uibutton(app.GridLayoutEdit, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.GridLayoutEdit, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.FiltersLabel = uilabel(app.GridLayoutEdit);
            app.FiltersLabel.Text = 'Filters';
            app.BrightnessLabel = uilabel(app.GridLayoutEdit);
            app.BrightnessLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayoutEdit);
            app.BrightnessSlider.Limits = [0 4];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.Enable = 'off';
            app.BrightnessSlider.Value = 1;
            app.TabGroup = uitabgroup(app.GridLayoutEdit);
            app.LowPassFilterTab = uitab(app.TabGroup);
            app.LowPassFilterTab.Title = 'Low-Pass Filter';
            app.GridLayoutLowPass = uigridlayout(app.LowPassFilterTab);
            app.ModeDropDownLabel = uilabel(app.GridLayoutLowPass);
            app.ModeDropDownLabel.Interpreter = 'latex';
            app.ModeDropDownLabel.Text = 'Mode';
            app.LowPassModeDropDown = uidropdown(app.GridLayoutLowPass);
            app.LowPassModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.LowPassModeDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassModeDropDownValueChanged, true);
            app.LowPassModeDropDown.Value = 'Ideal';
            app.RadiusSliderLabel = uilabel(app.GridLayoutLowPass);
            app.RadiusSliderLabel.Interpreter = 'latex';
            app.RadiusSliderLabel.Text = 'Radius';
            app.LowPassRadiusSlider = uislider(app.GridLayoutLowPass);
            app.LowPassRadiusSlider.Limits = [0 1];
            app.LowPassRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @LowPassRadiusSliderValueChanged, true);
            app.LowPassRadiusSlider.Value = 0.2;
            app.FilterOrderSpinnerLabel = uilabel(app.GridLayoutLowPass);
            app.FilterOrderSpinnerLabel.Visible = 'off';
            app.FilterOrderSpinnerLabel.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.LowPassFilterOrderSpinner = uispinner(app.GridLayoutLowPass);
            app.LowPassFilterOrderSpinner.Limits = [1 Inf];
            app.LowPassFilterOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterOrderSpinnerValueChanged, true);
            app.LowPassFilterOrderSpinner.Visible = 'off';
            app.LowPassFilterOrderSpinner.Value = 1;
            app.LowPassEquationLabel = uilabel(app.GridLayoutLowPass);
            app.LowPassEquationLabel.Interpreter = 'latex';
            app.LowPassEquationLabel.Text = '$H(u,v)=\begin{cases}1,&D(u,v)\le D_0\\0,&D(u,v)>D_0\end{cases}$';
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High-Pass Filter';
            app.GridLayoutHighPass = uigridlayout(app.HighPassFilterTab);
            app.ModeDropDownLabel_2 = uilabel(app.GridLayoutHighPass);
            app.ModeDropDownLabel_2.Interpreter = 'latex';
            app.ModeDropDownLabel_2.Text = 'Mode';
            app.HighPassModeDropDown = uidropdown(app.GridLayoutHighPass);
            app.HighPassModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.HighPassModeDropDown.ValueChangedFcn = createCallbackFcn(app, @HighPassModeDropDownValueChanged, true);
            app.HighPassModeDropDown.Value = 'Ideal';
            app.RadiusSliderLabel_2 = uilabel(app.GridLayoutHighPass);
            app.RadiusSliderLabel_2.Interpreter = 'latex';
            app.RadiusSliderLabel_2.Text = 'Radius';
            app.HighPassRadiusSlider = uislider(app.GridLayoutHighPass);
            app.HighPassRadiusSlider.Limits = [0 1];
            app.HighPassRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @HighPassRadiusSliderValueChanged, true);
            app.HighPassRadiusSlider.Value = 0.2;
            app.FilterOrderSpinnerLabel_2 = uilabel(app.GridLayoutHighPass);
            app.FilterOrderSpinnerLabel_2.Visible = 'off';
            app.FilterOrderSpinnerLabel_2.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel_2.Text = 'Filter Order';
            app.HighPassFilterOrderSpinner = uispinner(app.GridLayoutHighPass);
            app.HighPassFilterOrderSpinner.Limits = [1 Inf];
            app.HighPassFilterOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @HighPassFilterOrderSpinnerValueChanged, true);
            app.HighPassFilterOrderSpinner.Visible = 'off';
            app.HighPassFilterOrderSpinner.Value = 1;
            app.HighPassEquationLabel = uilabel(app.GridLayoutHighPass);
            app.HighPassEquationLabel.Interpreter = 'latex';
            app.HighPassEquationLabel.Text = '$H(u,v)=\begin{cases}0,&D(u,v)\le D_0\\1,&D(u,v)>D_0\end{cases}$';
            app.BandRejectTab = uitab(app.TabGroup);
            app.BandRejectTab.Title = 'Band Reject';
            app.GridLayoutBand = uigridlayout(app.BandRejectTab);
            app.ModeDropDownLabel_3 = uilabel(app.GridLayoutBand);
            app.ModeDropDownLabel_3.Interpreter = 'latex';
            app.ModeDropDownLabel_3.Text = 'Mode';
            app.BandModeDropDown = uidropdown(app.GridLayoutBand);
            app.BandModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.BandModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BandModeDropDownValueChanged, true);
            app.BandModeDropDown.Value = 'Ideal';
            app.CenterFrequencyD_0Label = uilabel(app.GridLayoutBand);
            app.CenterFrequencyD_0Label.Interpreter = 'latex';
            app.CenterFrequencyD_0Label.Text = {'Center Frequency'; '$D_0$'};
            app.BandCenterFreqSlider = uislider(app.GridLayoutBand);
            app.BandCenterFreqSlider.Limits = [0 1];
            app.BandCenterFreqSlider.ValueChangedFcn = createCallbackFcn(app, @BandCenterFreqSliderValueChanged, true);
            app.BandCenterFreqSlider.Value = 0.4;
            app.BandEquationLabel = uilabel(app.GridLayoutBand);
            app.BandEquationLabel.Interpreter = 'latex';
            app.BandEquationLabel.Text = '$H(u,v)=\begin{cases}0,&D_0-\frac{W}{2}\le D(u,v)\le D_0+\frac{W}{2}\\1,&\text{otherwise}\end{cases}$';
            app.CenterFrequencyD_0Label_2 = uilabel(app.GridLayoutBand);
            app.CenterFrequencyD_0Label_2.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_2.Text = {'Bandwidth'; '$W$'};
            app.BandBandwidthSlider = uislider(app.GridLayoutBand);
            app.BandBandwidthSlider.Limits = [0 1];
            app.BandBandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandBandwidthSliderValueChanged, true);
            app.BandBandwidthSlider.Value = 0.1;
            app.FilterOrderSpinnerLabel_3 = uilabel(app.GridLayoutBand);
            app.FilterOrderSpinnerLabel_3.Visible = 'off';
            app.FilterOrderSpinnerLabel_3.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel_3.Text = 'Filter Order';
            app.BandFilterOrderSpinner = uispinner(app.GridLayoutBand);
            app.BandFilterOrderSpinner.Limits = [1 Inf];
            app.BandFilterOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @BandFilterOrderSpinnerValueChanged, true);
            app.BandFilterOrderSpinner.Visible = 'off';
            app.BandFilterOrderSpinner.Value = 1;
            app.NotchRejectTab = uitab(app.TabGroup);
            app.NotchRejectTab.Title = 'Notch Reject';
            app.GridLayoutNotch = uigridlayout(app.NotchRejectTab);
            app.ModeDropDownLabel_4 = uilabel(app.GridLayoutNotch);
            app.ModeDropDownLabel_4.Interpreter = 'latex';
            app.ModeDropDownLabel_4.Text = 'Mode';
            app.NotchModeDropDown = uidropdown(app.GridLayoutNotch);
            app.NotchModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.NotchModeDropDown.ValueChangedFcn = createCallbackFcn(app, @NotchModeDropDownValueChanged, true);
            app.NotchModeDropDown.Value = 'Ideal';
            app.CenterFrequencyD_0Label_3 = uilabel(app.GridLayoutNotch);
            app.CenterFrequencyD_0Label_3.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_3.Text = {'Horizontal Location'; '$u_0$'};
            app.NotchHorizLocSlider = uislider(app.GridLayoutNotch);
            app.NotchHorizLocSlider.Limits = [-0.5 0.5];
            app.NotchHorizLocSlider.ValueChangedFcn = createCallbackFcn(app, @NotchHorizLocSliderValueChanged, true);
            app.CenterFrequencyD_0Label_4 = uilabel(app.GridLayoutNotch);
            app.CenterFrequencyD_0Label_4.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_4.Text = {'Vertical Location'; '$v_0$'};
            app.NotchVertLocSlider = uislider(app.GridLayoutNotch);
            app.NotchVertLocSlider.Limits = [-0.5 0.5];
            app.NotchVertLocSlider.ValueChangedFcn = createCallbackFcn(app, @NotchVertLocSliderValueChanged, true);
            app.FilterOrderSpinnerLabel_4 = uilabel(app.GridLayoutNotch);
            app.FilterOrderSpinnerLabel_4.Visible = 'off';
            app.FilterOrderSpinnerLabel_4.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel_4.Text = 'Filter Order';
            app.NotchFilterOrderSpinner = uispinner(app.GridLayoutNotch);
            app.NotchFilterOrderSpinner.Limits = [1 Inf];
            app.NotchFilterOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @NotchFilterOrderSpinnerValueChanged, true);
            app.NotchFilterOrderSpinner.Visible = 'off';
            app.NotchFilterOrderSpinner.Value = 1;
            app.CenterFrequencyD_0Label_5 = uilabel(app.GridLayoutNotch);
            app.CenterFrequencyD_0Label_5.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_5.Text = 'Radius $D_0$';
            app.NotchRadiusSlider = uislider(app.GridLayoutNotch);
            app.NotchRadiusSlider.Limits = [0 1];
            app.NotchRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @NotchRadiusSliderValueChanged, true);
            app.NotchRadiusSlider.Value = 0.1;
            app.NotchEquationLabel = uilabel(app.GridLayoutNotch);
            app.NotchEquationLabel.Interpreter = 'latex';
            app.NotchEquationLabel.Text = '$H(u,v)=\begin{cases}0,&D_1(u,v)\le D_0\ \text{or}\ D_2(u,v)\le D_0\\1,&\text{otherwise}\end{cases}$';
            app.CancelButton = uibutton(app.GridLayoutEdit, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.PreviewButton = uibutton(app.GridLayoutEdit, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Text = 'Preview';
            app.ApplyButton = uibutton(app.GridLayoutEdit, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.UndoButton = uibutton(app.GridLayoutEdit, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.RedoButton = uibutton(app.GridLayoutEdit, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = 'Redo';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.MainAxes = uiaxes(app.GridLayout2);
            title(app.MainAxes, 'Image')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            zlabel(app.MainAxes, 'Z')
            app.MainAxes.XAxisLocation = 'top';
            app.FrequencyAxes = uiaxes(app.GridLayout2);
            title(app.FrequencyAxes, 'Image (Frequency Domain)')
            xlabel(app.FrequencyAxes, 'Horizontal Frequency (cycles/pixel)')
            ylabel(app.FrequencyAxes, 'Vertical Frequency (cycles/pixel)')
            zlabel(app.FrequencyAxes, 'Z')
            app.FrequencyAxes.XAxisLocation = 'top';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end